classdef candidate_genes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TitleLabel                      matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        Part1Tab                        matlab.ui.container.Tab
        TextAreaLabel                   matlab.ui.control.Label
        UploadCandidatesTextArea        matlab.ui.control.TextArea
        UploadCandidatesButton          matlab.ui.control.Button
        DirectionsChooseSheetsLabel     matlab.ui.control.Label
        SheetsinfileLabel               matlab.ui.control.Label
        SheetsListBox                   matlab.ui.control.ListBox
        ChosensheetsListBoxLabel        matlab.ui.control.Label
        ChosensheetsListBox             matlab.ui.control.ListBox
        CandidateAddArrowButton         matlab.ui.control.Button
        CandidteDeleteArrowButton       matlab.ui.control.Button
        Part2Tab                        matlab.ui.container.Tab
        OptionalPanel                   matlab.ui.container.Panel
        UploadclinicaldataforKManalysisorLabel  matlab.ui.control.Label
        UploadKMDataTextArea            matlab.ui.control.TextArea
        UploadKMButton                  matlab.ui.control.Button
        UploadmutationsdataLabel        matlab.ui.control.Label
        UploadMutationsTextArea         matlab.ui.control.TextArea
        UploadMutationsButton           matlab.ui.control.Button
        Part3Tab                        matlab.ui.container.Tab
        DoPCAandClustergramcalculationsSwitchLabel  matlab.ui.control.Label
        DoPCAandClustergramcalculationsSwitch  matlab.ui.control.Switch
        SwitchLabel                     matlab.ui.control.Label
        SwitchStandartization           matlab.ui.control.Switch
        Part4Tab                        matlab.ui.container.Tab
        UploadPPT                       matlab.ui.control.Button
        UploadapowerpointtemplateLabel  matlab.ui.control.Label
        UploadPPTTextArea               matlab.ui.control.TextArea
        ChoosetheoutputdirectoryLabel   matlab.ui.control.Label
        ChooseOutputDirTextArea         matlab.ui.control.TextArea
        UploadOutputDir                 matlab.ui.control.Button
        DoneButton                      matlab.ui.control.Button
    end


    properties (Access = private)
        fullpath; % File path of the candidate genes excel file.
        fullpathKM; % File path of the clinical data for the km analysis.
        fullpathMutations; % File path of the mutations file for mutations anaylsis.
        fullpathPPT; % File path of the template powerpoint file.
        outputDir; % Output directory.
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SheetsListBox.Items = {};
            app.SheetsListBox.Value = {};
            app.ChosensheetsListBox.Items = {};
            app.fullpath = ""; 
            app.fullpathKM = ""; 
            app.fullpathMutations = "";
        end

        % Button pushed function: UploadCandidatesButton
        function UploadCandidatesButtonPushed(app, event)
            [filename, path] = uigetfile({'*.xlsx','.xls'},...
                'Please choose your candidate genes file');
            app.fullpath = fullfile(path, filename);
            app.UploadCandidatesTextArea.Value = {app.fullpath};
            [~, sheets] = xlsfinfo(app.fullpath);
            app.SheetsListBox.Items = sheets;
        end

        % Button pushed function: CandidateAddArrowButton
        function CandidateAddArrowButtonPushed(app, event)
            if ~isempty(app.SheetsListBox.Value)
                app.ChosensheetsListBox.Items = [app.ChosensheetsListBox.Items, ...
                    app.SheetsListBox.Value];
                logicalIndices = ismember(app.SheetsListBox.Items, app.SheetsListBox.Value);
                app.SheetsListBox.Items(logicalIndices) = [];
            end
        end

        % Button pushed function: CandidteDeleteArrowButton
        function CandidteDeleteArrowButtonPushed(app, event)
            if ~isempty(app.ChosensheetsListBox.Value)
                app.SheetsListBox.Items = [app.SheetsListBox.Items, ...
                    app.ChosensheetsListBox.Value];
                logicalIndices = ismember(app.ChosensheetsListBox.Items,...
                    app.ChosensheetsListBox.Value);
                app.ChosensheetsListBox.Items(logicalIndices) = [];
            end
        end

        % Button pushed function: UploadKMButton
        function UploadKMButtonPushed(app, event)
            [filename, path] = uigetfile({'*.xlsx','.xls'},...
                'Please choose your clinical data file');
            app.fullpathKM = fullfile(path, filename);
            app.UploadKMDataTextArea.Value = {app.fullpathKM};
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            if app.fullpath == ""
                message = sprintf('Genes experssion file must be uploaded');
                msgbox(message, 'No File Uploaded','warn');
                return
            end
            if isempty(app.ChosensheetsListBox.Items) 
                message = sprintf('No excel sheets chosen!');
                msgbox(message, 'Excel sheets not chosen','warn');
                return
            end
            if app.DoPCAandClustergramcalculationsSwitch.Value == "No"
                if app.fullpathKM == "" && app.fullpathMutations == ""
                    message = sprintf('You chose not to do clustergram or pca calculations\nYet you did not upload a clinical data file\nor a mutations file!\n\nPlease upload at least one of those.');
                    msgbox(message, 'Upload at least one file','warn');
                    return
                end
            end
            
            % ---- Passing relevant arguments to the next UIs
            % filename - file name of the genes expression excel file.
            % filename_km - file name of the clinical data excel file.
            % filenname_mut - file name of the mutationas excel file.
            % do_genes_expression_calculations - whethere to do pca and clustergram analysis.
            % do_clinical_calculations - whether to do clinical data calculations like KM or subtype histogram.
            % do_mutations_calculations - whether to do mutation data analysis.
            % sheets_names - the names of the excel sheets in the genes expression file.
            % use_cbioportal_not_lab_std -  Whether to use the standard of data
            % sources as in cbiportal or the standard that will be decided in the lab.
            
            setappdata(0,'filename', app.fullpath);
            setappdata(0, 'filename_km', app.fullpathKM);
            setappdata(0, 'filename_mut', app.fullpathMutations);
            setappdata(0, 'do_genes_expression_calculations',...
                app.DoPCAandClustergramcalculationsSwitch.Value);
            if app.fullpathKM == ""
                do_clinical_calculations = false;
            else
                do_clinical_calculations = true;
            end
            if app.fullpathMutations == ""
                do_mutation_analysis = false;
            else 
                do_mutation_analysis = true;
            end
            setappdata(0, 'do_clinical_calculations', do_clinical_calculations);
            setappdata(0, 'do_mutation_calculations', do_mutation_analysis);
            setappdata(0, 'sheets_names', app.ChosensheetsListBox.Items);
            setappdata(0, 'use_cbioportal_not_lab_std', app.SwitchStandartization.Value);
            expression_data_gui
            app.UIFigureCloseRequest();
            
        end

        % Button pushed function: UploadMutationsButton
        function UploadMutationsButtonPushed(app, event)
            [filename, path] = uigetfile({'*.xlsx','.xls'}, ...
                'Please choose your mutations data file');
            app.fullpathMutations = fullfile(path, filename);
            app.UploadMutationsTextArea.Value = {app.fullpathMutations};
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: UploadPPT
        function UploadPPTButtonPushed(app, event)
            [filename, path] = uigetfile({'*.pptx','.ppt'}, ...
                'Please choose your template file');
            app.fullpathPPT = fullfile(path, filename);
            app.UploadPPT.Value = {app.fullpathPPT};
        end

        % Button pushed function: UploadOutputDir
        function UploadOutputDirButtonPushed(app, event)
            path = uigetdir;
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Candidate Genes Analysis';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Part1Tab = uitab(app.TabGroup);
            app.Part1Tab.Title = 'Part 1';
            app.TextAreaLabel = uilabel(app.Part1Tab);
            app.TextAreaLabel.Text = 'Upload a file of the candidate genes & their expression per patient';
            app.UploadCandidatesTextArea = uitextarea(app.Part1Tab);
            app.UploadCandidatesButton = uibutton(app.Part1Tab, 'push');
            app.UploadCandidatesButton.ButtonPushedFcn = createCallbackFcn(app, @UploadCandidatesButtonPushed, true);
            app.UploadCandidatesButton.Text = 'Upload';
            app.DirectionsChooseSheetsLabel = uilabel(app.Part1Tab);
            app.DirectionsChooseSheetsLabel.Text = 'Choose which sheets to include in the analysis';
            app.SheetsinfileLabel = uilabel(app.Part1Tab);
            app.SheetsinfileLabel.Text = 'Sheets in file ';
            app.SheetsListBox = uilistbox(app.Part1Tab);
            app.SheetsListBox.Multiselect = 'on';
            app.SheetsListBox.Value = {'Item 1'};
            app.ChosensheetsListBoxLabel = uilabel(app.Part1Tab);
            app.ChosensheetsListBoxLabel.Text = 'Chosen sheets';
            app.ChosensheetsListBox = uilistbox(app.Part1Tab);
            app.ChosensheetsListBox.Multiselect = 'on';
            app.ChosensheetsListBox.Value = {'Item 1'};
            app.CandidateAddArrowButton = uibutton(app.Part1Tab, 'push');
            app.CandidateAddArrowButton.ButtonPushedFcn = createCallbackFcn(app, @CandidateAddArrowButtonPushed, true);
            app.CandidateAddArrowButton.Text = '→';
            app.CandidteDeleteArrowButton = uibutton(app.Part1Tab, 'push');
            app.CandidteDeleteArrowButton.ButtonPushedFcn = createCallbackFcn(app, @CandidteDeleteArrowButtonPushed, true);
            app.CandidteDeleteArrowButton.Text = '←';
            app.Part2Tab = uitab(app.TabGroup);
            app.Part2Tab.Title = 'Part 2';
            app.OptionalPanel = uipanel(app.Part2Tab);
            app.OptionalPanel.Title = '* Optional';
            app.UploadclinicaldataforKManalysisorLabel = uilabel(app.OptionalPanel);
            app.UploadclinicaldataforKManalysisorLabel.Text = 'Upload clinical data for KM analysis and other clinical data calculations';
            app.UploadKMDataTextArea = uitextarea(app.OptionalPanel);
            app.UploadKMButton = uibutton(app.OptionalPanel, 'push');
            app.UploadKMButton.ButtonPushedFcn = createCallbackFcn(app, @UploadKMButtonPushed, true);
            app.UploadKMButton.Text = 'Upload';
            app.UploadmutationsdataLabel = uilabel(app.OptionalPanel);
            app.UploadmutationsdataLabel.Text = 'Upload mutations data';
            app.UploadMutationsTextArea = uitextarea(app.OptionalPanel);
            app.UploadMutationsButton = uibutton(app.OptionalPanel, 'push');
            app.UploadMutationsButton.ButtonPushedFcn = createCallbackFcn(app, @UploadMutationsButtonPushed, true);
            app.UploadMutationsButton.Text = 'Upload';
            app.Part3Tab = uitab(app.TabGroup);
            app.Part3Tab.Title = 'Part 3';
            app.DoPCAandClustergramcalculationsSwitchLabel = uilabel(app.Part3Tab);
            app.DoPCAandClustergramcalculationsSwitchLabel.Text = {'Do PCA and Clustergram calculations?'; ''; ''; ''; ''; 'Note, if you choose ''No'' then either clinical data file of Mutations file '; 'should be uploaded as well.'};
            app.DoPCAandClustergramcalculationsSwitch = uiswitch(app.Part3Tab, 'slider');
            app.DoPCAandClustergramcalculationsSwitch.Items = {'Yes', 'No'};
            app.DoPCAandClustergramcalculationsSwitch.Value = 'Yes';
            app.SwitchLabel = uilabel(app.Part3Tab);
            app.SwitchLabel.Text = 'Will you use the lab''s standartization of the input files or the cbioportal''s ones?';
            app.SwitchStandartization = uiswitch(app.Part3Tab, 'slider');
            app.SwitchStandartization.Items = {'Lab', 'Cbioportal'};
            app.SwitchStandartization.Value = 'Lab';
            app.Part4Tab = uitab(app.TabGroup);
            app.Part4Tab.Title = 'Part 4';
            app.UploadPPT = uibutton(app.Part4Tab, 'push');
            app.UploadPPT.ButtonPushedFcn = createCallbackFcn(app, @UploadPPTButtonPushed, true);
            app.UploadPPT.Text = 'Upload';
            app.UploadapowerpointtemplateLabel = uilabel(app.Part4Tab);
            app.UploadapowerpointtemplateLabel.Text = 'Upload a power point template';
            app.UploadPPTTextArea = uitextarea(app.Part4Tab);
            app.ChoosetheoutputdirectoryLabel = uilabel(app.Part4Tab);
            app.ChoosetheoutputdirectoryLabel.Text = 'Choose the output directory';
            app.ChooseOutputDirTextArea = uitextarea(app.Part4Tab);
            app.UploadOutputDir = uibutton(app.Part4Tab, 'push');
            app.UploadOutputDir.ButtonPushedFcn = createCallbackFcn(app, @UploadOutputDirButtonPushed, true);
            app.UploadOutputDir.Text = 'Upload';
            app.DoneButton = uibutton(app.Part4Tab, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = candidate_genes

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end