classdef candidate_genes2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TitleLabel                      matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        Part1Tab                        matlab.ui.container.Tab
        OutputanalysisofbarplotsofgeneexpressionCheckBox  matlab.ui.control.CheckBox
        DoKaplanMeieranalysisbasedongeneexpressionCheckBox  matlab.ui.control.CheckBox
        DoKaplanMeieranalysisbasedonclusteringmethodsCheckBox  matlab.ui.control.CheckBox
        DoKaplanMeieranalysisbasedongeneexpressionandmutationsCheckBox  matlab.ui.control.CheckBox
        DomutationanalysisCheckBox      matlab.ui.control.CheckBox
        OutputhistogramsbasedoncancersubtypesCheckBox  matlab.ui.control.CheckBox
        Part2Tab                        matlab.ui.container.Tab
        UploadCandidatesButton          matlab.ui.control.Button
        TextAreaLabel                   matlab.ui.control.Label
        UploadCandidatesTextArea        matlab.ui.control.TextArea
        ChoosethenomenclaturewhichwasusedforthegenesLabel  matlab.ui.control.Label
        DropDown_nomenclature           matlab.ui.control.DropDown
        ChoosesheettoworkonLabel        matlab.ui.control.Label
        DropDown_sheet                  matlab.ui.control.DropDown
        UploadCandidatesButton_3        matlab.ui.control.Button
        Part3Tab                        matlab.ui.container.Tab
        PleaseenterdrivergenesnamehastobeaccurateLabel  matlab.ui.control.Label
        UploadCandidatesTextArea_2      matlab.ui.control.TextArea
        UploadCandidatesButton_2        matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        CandidateAddArrowButton         matlab.ui.control.Button
        CandidteDeleteArrowButton       matlab.ui.control.Button
        GenesinfileLabel                matlab.ui.control.Label
        SheetsListBox                   matlab.ui.control.ListBox
        ChosengenesListBoxLabel         matlab.ui.control.Label
        ChosengenesListBox              matlab.ui.control.ListBox
        ChoosewhichimagestooutputLabel  matlab.ui.control.Label
        DropDown_4                      matlab.ui.control.DropDown
        UploadCandidatesButton_4        matlab.ui.control.Button
        Part4Tab                        matlab.ui.container.Tab
        UploadOutputDir                 matlab.ui.control.Button
        DoneButton                      matlab.ui.control.Button
        ChoosetheoutputdirectoryLabel   matlab.ui.control.Label
        ChooseOutputDirTextArea         matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        do_genes_expression_calculations = 0;
        do_km_analysis = 0;
        do_km_by_clustering = 0;
        do_km_by_mutation_and_expression = 0;      
        do_mutation_analysis = 0;
        do_subtype_histograms = 0;
        gene_expression_filename = 0; 
        sheet_name;
        use_cbioportal_not_lab_std;
        gene_nomenclature;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Part 1 parameters
            app.OutputanalysisofbarplotsofgeneexpressionCheckBox.Value = 0;
            app.DoKaplanMeieranalysisbasedongeneexpressionCheckBox.Value = 0;
            app.DoKaplanMeieranalysisbasedonclusteringmethodsCheckBox.Value = 0;
            app.DoKaplanMeieranalysisbasedongeneexpressionandmutationsCheckBox.Value = 0;
            app.DomutationanalysisCheckBox.Value = 0;
            app.OutputhistogramsbasedoncancersubtypesCheckBox.Value = 0;
            
            % Part 2 parameters
            app.use_cbioportal_not_lab_std = app.DropDown.Value;
            app.gene_nomenclature = app.DropDown_nomenclature.Value;
        end

        % Value changed function: 
        % OutputanalysisofbarplotsofgeneexpressionCheckBox
        function OutputanalysisofbarplotsofgeneexpressionCheckBoxValueChanged(app, event)
            app.do_genes_expression_calculations = app.OutputanalysisofbarplotsofgeneexpressionCheckBox.Value;
        end

        % Value changed function: 
        % DoKaplanMeieranalysisbasedongeneexpressionCheckBox
        function DoKaplanMeieranalysisbasedongeneexpressionCheckBoxValueChanged(app, event)
           app.do_km_analysis = app.DoKaplanMeieranalysisbasedongeneexpressionCheckBox.Value;
        end

        % Value changed function: 
        % DoKaplanMeieranalysisbasedonclusteringmethodsCheckBox
        function DoKaplanMeieranalysisbasedonclusteringmethodsCheckBoxValueChanged(app, event)
            app.do_km_by_clustering = app.DoKaplanMeieranalysisbasedonclusteringmethodsCheckBox.Value;
        end

        % Value changed function: 
        % DoKaplanMeieranalysisbasedongeneexpressionandmutationsCheckBox
        function DoKaplanMeieranalysisbasedongeneexpressionandmutationsCheckBoxValueChanged(app, event)
            app.do_km_by_mutation_and_expression = app.DoKaplanMeieranalysisbasedongeneexpressionandmutationsCheckBox.Value;    
        end

        % Value changed function: DomutationanalysisCheckBox
        function DomutationanalysisCheckBoxValueChanged(app, event)
            app.do_mutation_analysis = app.DomutationanalysisCheckBox.Value;
        end

        % Value changed function: 
        % OutputhistogramsbasedoncancersubtypesCheckBox
        function OutputhistogramsbasedoncancersubtypesCheckBoxValueChanged(app, event)
            app.do_subtype_histograms = app.OutputhistogramsbasedoncancersubtypesCheckBox.Value;           
        end

        % Button pushed function: UploadCandidatesButton
        function UploadCandidatesButtonPushed(app, event)
            [filename, path] = uigetfile({'*.xlsx','.xls'},...
                'Please choose your candidate genes file');
            if isequal(filename, 0)
                app.gene_expression_filename = 0;
                app.UploadCandidatesTextArea.Value = {'No file chosen'}; 
                app.DropDown_sheet.Items = {};
                app.DropDown_sheet.Enable = 'off';
            else
                app.gene_expression_filename = fullfile(path, filename);
                app.UploadCandidatesTextArea.Value = {app.gene_expression_filename};
                [~, sheets] = xlsfinfo(app.gene_expression_filename);
                app.DropDown_sheet.Items = sheets;
                app.DropDown_sheet.Enable = 'on';
                app.sheet_name = app.DropDown_sheet.Items{1};
            end
        end

        % Callback function
        function DropDownValueChanged(app, event)

        end

        % Value changed function: DropDown_nomenclature
        function DropDown_nomenclatureValueChanged(app, event)
            app.gene_nomenclature = app.DropDown_nomenclature.Value; 
        end

        % Value changed function: DropDown_sheet
        function DropDown_sheetValueChanged(app, event)
            app.sheet_name = app.DropDown_sheet.Value;
        end

        % Button pushed function: UploadCandidatesButton_3
        function UploadCandidatesButton_3Pushed(app, event)
            if app.gene_expression_filename == 0
                msgbox('Please upload gene expression excel file first!', 'Error', 'error');
                return;
            else
                if strcmp(app.gene_nomenclature, 'entrez') 
                numericData = numericData(:, 2:end); 
%         patientsNames = txtData(1, 2:end);
%         geneNames = numericData(:, 1); 
%     elseif strcmp(gene_nomenclature, 'hugo')
%         % if nomenclature == 'hugo' numericData stays the same.
%         patientsNames = txtData(1, 2:end);
%         geneNames = txtData(2:end, 1); 
%     elseif strcmp(gene_nomenclature, 'both')
%         numericData = numericData(:, 2:end);
%         patientsNames = txtData(1, 3:end);
%         geneNames = txtData(2:end, 1); 
    end
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Candidate Genes Analysis';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Part1Tab = uitab(app.TabGroup);
            app.Part1Tab.Title = 'Part 1';
            app.OutputanalysisofbarplotsofgeneexpressionCheckBox = uicheckbox(app.Part1Tab);
            app.OutputanalysisofbarplotsofgeneexpressionCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutputanalysisofbarplotsofgeneexpressionCheckBoxValueChanged, true);
            app.OutputanalysisofbarplotsofgeneexpressionCheckBox.Text = 'Output analysis of bar plots of gene expression?';
            app.DoKaplanMeieranalysisbasedongeneexpressionCheckBox = uicheckbox(app.Part1Tab);
            app.DoKaplanMeieranalysisbasedongeneexpressionCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoKaplanMeieranalysisbasedongeneexpressionCheckBoxValueChanged, true);
            app.DoKaplanMeieranalysisbasedongeneexpressionCheckBox.Text = 'Do Kaplan Meier analysis based on gene expression?';
            app.DoKaplanMeieranalysisbasedonclusteringmethodsCheckBox = uicheckbox(app.Part1Tab);
            app.DoKaplanMeieranalysisbasedonclusteringmethodsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoKaplanMeieranalysisbasedonclusteringmethodsCheckBoxValueChanged, true);
            app.DoKaplanMeieranalysisbasedonclusteringmethodsCheckBox.Text = 'Do Kaplan Meier analysis based on clustering methods?';
            app.DoKaplanMeieranalysisbasedongeneexpressionandmutationsCheckBox = uicheckbox(app.Part1Tab);
            app.DoKaplanMeieranalysisbasedongeneexpressionandmutationsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoKaplanMeieranalysisbasedongeneexpressionandmutationsCheckBoxValueChanged, true);
            app.DoKaplanMeieranalysisbasedongeneexpressionandmutationsCheckBox.Text = 'Do Kaplan Meier analysis based on gene expression and mutations?';
            app.DomutationanalysisCheckBox = uicheckbox(app.Part1Tab);
            app.DomutationanalysisCheckBox.ValueChangedFcn = createCallbackFcn(app, @DomutationanalysisCheckBoxValueChanged, true);
            app.DomutationanalysisCheckBox.Text = 'Do mutation analysis?';
            app.OutputhistogramsbasedoncancersubtypesCheckBox = uicheckbox(app.Part1Tab);
            app.OutputhistogramsbasedoncancersubtypesCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutputhistogramsbasedoncancersubtypesCheckBoxValueChanged, true);
            app.OutputhistogramsbasedoncancersubtypesCheckBox.Text = 'Output histograms based on cancer subtypes?';
            app.Part2Tab = uitab(app.TabGroup);
            app.Part2Tab.Title = 'Part 2';
            app.UploadCandidatesButton = uibutton(app.Part2Tab, 'push');
            app.UploadCandidatesButton.ButtonPushedFcn = createCallbackFcn(app, @UploadCandidatesButtonPushed, true);
            app.UploadCandidatesButton.Text = 'Upload';
            app.TextAreaLabel = uilabel(app.Part2Tab);
            app.TextAreaLabel.Text = 'Upload a file of the candidate genes & their expression per patient';
            app.UploadCandidatesTextArea = uitextarea(app.Part2Tab);
            app.UploadCandidatesTextArea.Editable = 'off';
            app.ChoosethenomenclaturewhichwasusedforthegenesLabel = uilabel(app.Part2Tab);
            app.ChoosethenomenclaturewhichwasusedforthegenesLabel.Text = 'Choose the nomenclature which was used for the genes';
            app.DropDown_nomenclature = uidropdown(app.Part2Tab);
            app.DropDown_nomenclature.Items = {'Both', 'Hugo', 'Entrez'};
            app.DropDown_nomenclature.ValueChangedFcn = createCallbackFcn(app, @DropDown_nomenclatureValueChanged, true);
            app.DropDown_nomenclature.Value = 'Both';
            app.ChoosesheettoworkonLabel = uilabel(app.Part2Tab);
            app.ChoosesheettoworkonLabel.Text = 'Choose sheet to work on:';
            app.DropDown_sheet = uidropdown(app.Part2Tab);
            app.DropDown_sheet.Items = {};
            app.DropDown_sheet.ValueChangedFcn = createCallbackFcn(app, @DropDown_sheetValueChanged, true);
            app.DropDown_sheet.Enable = 'off';
            app.DropDown_sheet.Value = {};
            app.UploadCandidatesButton_3 = uibutton(app.Part2Tab, 'push');
            app.UploadCandidatesButton_3.ButtonPushedFcn = createCallbackFcn(app, @UploadCandidatesButton_3Pushed, true);
            app.UploadCandidatesButton_3.Text = 'Next Part';
            app.Part3Tab = uitab(app.TabGroup);
            app.Part3Tab.Title = 'Part 3';
            app.PleaseenterdrivergenesnamehastobeaccurateLabel = uilabel(app.Part3Tab);
            app.PleaseenterdrivergenesnamehastobeaccurateLabel.Text = 'Please enter driver gene''s name (has to be accurate!)';
            app.UploadCandidatesTextArea_2 = uitextarea(app.Part3Tab);
            app.UploadCandidatesButton_2 = uibutton(app.Part3Tab, 'push');
            app.UploadCandidatesButton_2.Enable = 'off';
            app.UploadCandidatesButton_2.Text = 'Set name';
            app.TextArea = uitextarea(app.Part3Tab);
            app.TextArea.Editable = 'off';
            app.TextArea.Enable = 'off';
            app.CandidateAddArrowButton = uibutton(app.Part3Tab, 'push');
            app.CandidateAddArrowButton.Enable = 'off';
            app.CandidateAddArrowButton.Text = '→';
            app.CandidteDeleteArrowButton = uibutton(app.Part3Tab, 'push');
            app.CandidteDeleteArrowButton.Enable = 'off';
            app.CandidteDeleteArrowButton.Text = '←';
            app.GenesinfileLabel = uilabel(app.Part3Tab);
            app.GenesinfileLabel.Enable = 'off';
            app.GenesinfileLabel.Text = 'Genes in file ';
            app.SheetsListBox = uilistbox(app.Part3Tab);
            app.SheetsListBox.Multiselect = 'on';
            app.SheetsListBox.Enable = 'off';
            app.SheetsListBox.Value = {'Item 1'};
            app.ChosengenesListBoxLabel = uilabel(app.Part3Tab);
            app.ChosengenesListBoxLabel.Enable = 'off';
            app.ChosengenesListBoxLabel.Text = 'Chosen genes';
            app.ChosengenesListBox = uilistbox(app.Part3Tab);
            app.ChosengenesListBox.Multiselect = 'on';
            app.ChosengenesListBox.Enable = 'off';
            app.ChosengenesListBox.Value = {'Item 1'};
            app.ChoosewhichimagestooutputLabel = uilabel(app.Part3Tab);
            app.ChoosewhichimagestooutputLabel.Enable = 'off';
            app.ChoosewhichimagestooutputLabel.Text = 'Choose which images to output';
            app.DropDown_4 = uidropdown(app.Part3Tab);
            app.DropDown_4.Items = {'None', 'All', 'By Specific genes', ''};
            app.DropDown_4.Enable = 'off';
            app.DropDown_4.Value = 'None';
            app.UploadCandidatesButton_4 = uibutton(app.Part3Tab, 'push');
            app.UploadCandidatesButton_4.Enable = 'off';
            app.UploadCandidatesButton_4.Text = 'Set image choice';
            app.Part4Tab = uitab(app.TabGroup);
            app.Part4Tab.Title = 'Part 4';
            app.UploadOutputDir = uibutton(app.Part4Tab, 'push');
            app.UploadOutputDir.Text = 'Upload';
            app.DoneButton = uibutton(app.Part4Tab, 'push');
            app.DoneButton.Text = 'Done';
            app.ChoosetheoutputdirectoryLabel = uilabel(app.Part4Tab);
            app.ChoosetheoutputdirectoryLabel.Text = 'Choose the output directory';
            app.ChooseOutputDirTextArea = uitextarea(app.Part4Tab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = candidate_genes2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end