classdef expression_data_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GeneExpressiondataLabel        matlab.ui.control.Label
        GenesnamesnomenclatureButtonGroup  matlab.ui.container.ButtonGroup
        HugoButton                     matlab.ui.control.RadioButton
        EntrezButton                   matlab.ui.control.RadioButton
        BothButton                     matlab.ui.control.RadioButton
        OkButton                       matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        MainGenesnamePanel             matlab.ui.container.Panel
        GenesEntreznumberEditFieldLabel  matlab.ui.control.Label
        GenesEntreznumberEditField     matlab.ui.control.NumericEditField
        GenesHugoSymbolEditFieldLabel  matlab.ui.control.Label
        GenesHugoSymbolEditField       matlab.ui.control.EditField
        DoneButton                     matlab.ui.control.Button
    end


    properties (Access = private)
        gene_nomenclature
        last_clicked = "";
    end


    methods (Access = private)

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            if app.HugoButton.Value == 1 || app.BothButton.Value == 1
                disp('hello')
                app.GenesEntreznumberEditField.Enable = 'off';
                app.GenesEntreznumberEditField.Editable = 'off';
                app.GenesHugoSymbolEditField.Enable = 'on';
                app.GenesHugoSymbolEditField.Editable = 'on';
                if app.HugoButton.Value == 1
                    app.gene_nomenclature = 'hugo';
                    app.last_clicked = 'hugo';
                elseif app.BothButton.Value == 1
                    app.gene_nomenclature = 'both';
                    app.last_clicked = 'both';
                end
            elseif app.EntrezButton.Value == 1
                app.GenesHugoSymbolEditField.Enable = 'off';
                app.GenesHugoSymbolEditField.Editable = 'off';
                app.GenesEntreznumberEditField.Enable = 'on';
                app.GenesEntreznumberEditField.Editable= 'on';
                app.gene_nomenclature = 'entrez';
                app.last_clicked = 'entrez';
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            if app.last_clicked == ""
               message = sprintf('No nomenclature chosen!');
               msgbox(message, 'Choose nomenclature','warn');
               return
            end 
            if app.GenesHugoSymbolEditField.Value == "" || app.GenesEntreznumberEditField.Value == 0
                message = sprintf('No gene value entered in text field');
                msgbox(message, 'No gene value', 'warn');
                return
            end
            if app.last_clicked == "hugo" || app.last_clicked == "both"
                setappdata(0, 'gene_name', app.GenesHugoSymbolEditField.Value);
            elseif app.last_clicked == "entrez"
                setappdata(0, 'gene_name', app.EntrezButton.Value);
            end
            setappdata(0, 'gene_nomenclature', app.gene_nomenclature);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.GeneExpressiondataLabel = uilabel(app.UIFigure);
            app.GeneExpressiondataLabel.Text = 'Gene Expression data';
            app.GenesnamesnomenclatureButtonGroup = uibuttongroup(app.UIFigure);
            app.GenesnamesnomenclatureButtonGroup.Title = 'Genes'' names nomenclature';
            app.HugoButton = uiradiobutton(app.GenesnamesnomenclatureButtonGroup);
            app.HugoButton.Text = 'Hugo';
            app.HugoButton.Value = true;
            app.EntrezButton = uiradiobutton(app.GenesnamesnomenclatureButtonGroup);
            app.EntrezButton.Text = 'Entrez';
            app.BothButton = uiradiobutton(app.GenesnamesnomenclatureButtonGroup);
            app.BothButton.Text = 'Both*';
            app.OkButton = uibutton(app.GenesnamesnomenclatureButtonGroup, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.Label = uilabel(app.GenesnamesnomenclatureButtonGroup);
            app.Label.Text = {'* Hugo will be used, but it is '; 'important to state whether Entrez '; 'is used as well.'};
            app.MainGenesnamePanel = uipanel(app.UIFigure);
            app.MainGenesnamePanel.Title = 'Main Gene''s name';
            app.GenesEntreznumberEditFieldLabel = uilabel(app.MainGenesnamePanel);
            app.GenesEntreznumberEditFieldLabel.Enable = 'off';
            app.GenesEntreznumberEditFieldLabel.Text = 'Gene''s Entrez number';
            app.GenesEntreznumberEditField = uieditfield(app.MainGenesnamePanel, 'numeric');
            app.GenesEntreznumberEditField.ValueDisplayFormat = '%.0f';
            app.GenesEntreznumberEditField.Enable = 'off';
            app.GenesHugoSymbolEditFieldLabel = uilabel(app.MainGenesnamePanel);
            app.GenesHugoSymbolEditFieldLabel.Enable = 'off';
            app.GenesHugoSymbolEditFieldLabel.Text = 'Gene''s Hugo Symbol';
            app.GenesHugoSymbolEditField = uieditfield(app.MainGenesnamePanel, 'text');
            app.GenesHugoSymbolEditField.Enable = 'off';
            app.DoneButton = uibutton(app.MainGenesnamePanel, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
        end
    end

    methods (Access = public)

        % Construct app
        function app = expression_data_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end